classdef Lloyd_App_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        NumberofAgentsEditFieldLabel    matlab.ui.control.Label
        NumberofAgentsEditField         matlab.ui.control.NumericEditField
        SideLengthmEditFieldLabel       matlab.ui.control.Label
        SideLengthmEditField            matlab.ui.control.NumericEditField
        PartitionNumberEditFieldLabel   matlab.ui.control.Label
        PartitionNumberEditField        matlab.ui.control.NumericEditField
        RadiusofObservationEditFieldLabel  matlab.ui.control.Label
        RadiusofObservationEditField    matlab.ui.control.NumericEditField
        RadiusofCommunicationEditFieldLabel  matlab.ui.control.Label
        RadiusofCommunicationEditField  matlab.ui.control.NumericEditField
        VelocityDropDownLabel           matlab.ui.control.Label
        VelocityDropDown                matlab.ui.control.DropDown
        MaximumVelocitymminEditFieldLabel  matlab.ui.control.Label
        MaximumVelocitymminEditField    matlab.ui.control.NumericEditField
        DensityTypeDropDownLabel        matlab.ui.control.Label
        DensityTypeDropDown             matlab.ui.control.DropDown
        DensityTimeVarianceDropDownLabel  matlab.ui.control.Label
        DensityTimeVarianceDropDown     matlab.ui.control.DropDown
        DensityFunctionEditFieldLabel   matlab.ui.control.Label
        DensityFunctionEditField        matlab.ui.control.EditField
        DelayEditFieldLabel             matlab.ui.control.Label
        DelayEditField                  matlab.ui.control.NumericEditField
        FinalIterationminEditFieldLabel  matlab.ui.control.Label
        FinalIterationminEditField      matlab.ui.control.NumericEditField
        AlgorithmDropDownLabel          matlab.ui.control.Label
        AlgorithmDropDown               matlab.ui.control.DropDown
        PlayButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        RandomizePositionsButton        matlab.ui.control.Button
        NameofCostFunction2EditFieldLabel  matlab.ui.control.Label
        NameofCostFunction2EditField    matlab.ui.control.EditField
        CostFunction2YAxisEditFieldLabel  matlab.ui.control.Label
        CostFunction2YAxisEditField     matlab.ui.control.EditField
        CurrentIterationminEditFieldLabel  matlab.ui.control.Label
        CurrentIterationminEditField    matlab.ui.control.NumericEditField
        VelocityMovementScaleEditFieldLabel  matlab.ui.control.Label
        VelocityMovementScaleEditField  matlab.ui.control.NumericEditField
        DensityMatrixNameEditFieldLabel  matlab.ui.control.Label
        DensityMatrixNameEditField      matlab.ui.control.EditField
    end

    
    methods (Access = private)
        
        %%%%%%%%%%%%%%%%%%%%%%%%% THESE ARE LOCAL PLOTTING FUNCTIONS %%%%%%%%%%%%%%%%%%%%%%%
        
        function [] = circle_Maker(app, x_centre,y_centre,r_o)
            % Draws a circle of specified radius around point (x_centre,y_centre)
            
            theta = linspace(0,2*pi,100);
            x = r_o.*cos(theta) + x_centre;
            y = r_o.*sin(theta) + y_centre;
            
            plot(app.UIAxes,x,y,'magenta')
        end
        
        function [] = Plot_Assigned_Points(app, Agent_Points, Number_of_Agents,...
                colours_energy,Agent_Positions,sides)
            
            for i = 1:Number_of_Agents
                hold(app.UIAxes3, 'on')
                scatter(app.UIAxes3, Agent_Points{1,i}(:,1),Agent_Points{1,i}(:,2),...
                    'MarkerEdgeColor',colours_energy(i,:));
                hold(app.UIAxes3, 'on')
                scatter(app.UIAxes3, Agent_Positions(i,1),Agent_Positions(i,2),50,...
                    colours_energy(i,:),'filled','diamond')
                label_agent_index = num2str(i);
                t.FontSize = 15;
                text(app.UIAxes3, Agent_Positions(i,1) + sides/65, Agent_Positions(i,2) + sides/65,...
                    label_agent_index);
                app.UIAxes3.XLim = [0 app.SideLengthmEditField.Value];
                app.UIAxes3.YLim = [0 app.SideLengthmEditField.Value];
            end
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% END OF LOCAL FUNCTIONS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Generates random data upon startup (3rd column is just the number of the agent)
            
            n = app.NumberofAgentsEditField.Value;
            L = app.SideLengthmEditField.Value;
            
            Agent_Positions = zeros(n,3);
            
            for i = 1:n
                Agent_Positions(i,1) = L*((.51-.49)*rand+.49);
                Agent_Positions(i,2) = L*((.51-.49)*rand+.49);
                Agent_Positions(i,3) = i;
            end
            
            app.UITable.Data = Agent_Positions;
        end

        % Button pushed function: RandomizePositionsButton
        function RandomizePositionsButtonPushed(app, event)
            %Randomizes data if the randomize button is pushed
            
            n = app.NumberofAgentsEditField.Value;
            L = app.SideLengthmEditField.Value;
            
            Agent_Positions = zeros(n,2);
            
            for i = 1:n
                Agent_Positions(i,1) = L*((.51-.49)*rand+.49);
                Agent_Positions(i,2) = L*((.51-.49)*rand+.49);
            end
            
            app.UITable.Data(:,1:2) = Agent_Positions;
        end

        % Callback function
        function StopButtonPushed(app, event)
            app.CurrentIterationminEditField.Value = app.FinalIterationminEditField.Value+1;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            %% This is the main body of the code. It executes Lloyd's algorithm when the start button is pressed.
            
            close('all');clc;close all;
            
            fprintf('The play button has been pushed. The simulation is starting now.\n');
            
            % NOTE: SOME DOCUMENTATION MAY REFER TO THIS FUNCTION AS LLOYDSPLAY
            
            % Get all of the input values that we'll need
            sides = app.SideLengthmEditField.Value;
            Partition_Number = app.PartitionNumberEditField.Value;
            Agent_Positions = app.UITable.Data(:,1:2);
            Number_of_Agents = app.NumberofAgentsEditField.Value;
            max_velocity = app.MaximumVelocitymminEditField.Value;
            Density_Type = app.DensityTypeDropDown.Value;
            Velocity_Type = app.VelocityDropDown.Value;
            final_iteration = app.FinalIterationminEditField.Value;
            r_o = app.RadiusofObservationEditField.Value;
            r_c = app.RadiusofCommunicationEditField.Value;
            Density_variation_type = app.DensityTimeVarianceDropDown.Value;
            delay = app.DelayEditField.Value;
            algorithm_type = app.AlgorithmDropDown.Value;
            app.CurrentIterationminEditField.Value = 1;
            
            % Unused variable
            %             arena_dimensions = app.SideLengthmEditField.Value;
            
            for i = 1:Number_of_Agents
                colours_energy(i,:) = rand(1,3); % Generate colours for energy example
            end
            
            %Assign numerical values to all of the decisions we make in the app
            if strcmpi(Density_Type, 'Function')
                Density_Type = 1;
            else
                Density_Type = 2;
            end
            
            if strcmpi(Velocity_Type, 'Constant Velocity')
                Velocity_Type = 1;
            else
                Velocity_Type = 2;
            end
            
            if strcmpi(algorithm_type, 'Regular')
                algorithm_type = 1;
            else
                algorithm_type = 2;
            end
            
            if strcmpi(Density_variation_type, 'Static')
                Density_variation_type = 1;
            else
                Density_variation_type = 2;
            end
            
            
            if Velocity_Type == 1
                MOVEMENTSCALE = 1;
                velocity = app.VelocityMovementScaleEditField.Value;
            else
                MOVEMENTSCALE = app.VelocityMovementScaleEditField.Value;
                velocity = 1;
            end
            
            History = [];
            for i = 1 : Number_of_Agents
                History(1:1,(2*i-1):2*i) = Agent_Positions(i,1:2);
            end
            
            if Density_Type == 1
                Density = str2sym(app.DensityFunctionEditField.Value);
                % Display the density function
                fprintf('The density function is %s\n',char(Density))
            elseif Density_Type == 2
                [~,Density] = Density_Position_Generator(Number_of_Agents, Density_Type,sides, Partition_Number);
                %                 Density = load(app.DensityMatrixNameEditField.Value, '-mat');
            end
            
            fprintf('Setting up the plots...\n');
            
            syms x y z
            [yy,xx] = meshgrid(linspace(0,sides,sides*Partition_Number)); %If you want contours, then you need variable density!!
            Agent_Positions = Fixing_Starting_Positions(Agent_Positions,Partition_Number,sides);
            
            j = 1;
            Percent_Covered = [];
            distance_travelled = zeros(1);
            if Density_Type == 1
                Density = fun_to_arr(Density,sides,Partition_Number);
                % else
                % Density = Density.D; %convert struct to array (variable must be named D originally)
            end
            
            % fprintf('%d\n',Density(1,1)) % fprintf density?
            
            Total_Mass = sum(Density, 'all');
            
            Initial_Mass = Total_Mass;
            
%             fprintf('The initial number (mass) of flowers that need to be pollinated in the area are %d.\n',Initial_Mass);
            
            % %% Energy Example (ONLY WORKS IF CUSTOM ALGORITHM IS SELECTED -> SEE DOCUMENTATION)
            
            E = zeros(1,Number_of_Agents);
            for i = 1:Number_of_Agents
                E(i) = 100; % Assume agents have 100% of energy at the start
            end
            %             E(2) = 40; %Just to make it different, allow other agents to have less energy
            %             E(3) = 20;
            
            
            Density_cumulative = Density;
            count = 1;
            
            %-------------------------------------------------------- Loop -----------------------------------------------------%
            
            fprintf('Plotting has begun...\n');
            
            while (count <= final_iteration && app.CurrentIterationminEditField.Value > 0)
                
                pp.CurrentIterationminEditField.Value = count;
                % Retain previous position for conditional pollination (small movement indicates at centroid of voronai region
                Old_Agent_Positions = Agent_Positions;
                
                [coms, com_mat] = communication_fun(Agent_Positions,r_c,Number_of_Agents,algorithm_type);
                count = count + 1;
                
                %% Plot Communication Graph %%
                % We first clear the old plots and make the necessary amount of colours:
                cla(app.UIAxes)
                cla(app.UIAxes2)
                cla(app.UIAxes3)
                cla(app.UIAxes4)
                n_connected_subgraphs = size(coms,2);
                %                 close('all')
                
                % Issue with initializations colours matrix (rows). Initialized # of rows based on inital value
                % of n_connected_subgraphs, but n_connected_subgraphs can change after each loop while colours
                % remains the same size.
                
                %                 if count == 2
                for i = 1:n_connected_subgraphs
                    
                    colours(i,:) = rand(1,3);
                    % Initialize colours
                end
                %                 end
                
                for i = 1:size(Agent_Positions,1)
                    scatter(app.UIAxes4,Agent_Positions(:,1),Agent_Positions(:,2));
                    label_agent_index = num2str(i);
                    text(app.UIAxes4, Agent_Positions(i,1) + sides/65, Agent_Positions(i,2) + sides/65, label_agent_index);
                    hold(app.UIAxes4, 'on')
                end
                
                % fprintf("n_connected_subgraphs = %d\n",n_connected_subgraphs);
                % fprintf("size(colors,1) = %d\n",size(colours,1));
                
                for i = 1:n_connected_subgraphs
                    for j = 1:size(coms{1,i},1)
                        for k = 1:size(coms{1,i},1)
                            if com_mat(coms{1,i}(j,1),coms{1,i}(k,1)) == 1
                                x1 = Agent_Positions(coms{1,i}(j,1),1);
                                y1 = Agent_Positions(coms{1,i}(j,1),2);
                                x2 = Agent_Positions(coms{1,i}(k,1),1);
                                y2 = Agent_Positions(coms{1,i}(k,1),2);
                                hold(app.UIAxes4, 'on')
                                
                                %  fprintf('i = %d\n',i);
                                
                                plot(app.UIAxes4,[x1 x2],[y1 y2],'Color',colours(i,:));
                                hold(app.UIAxes4, 'on')
                            end
                        end
                    end
                end
                %% End of Communication Plot %%
                
                Agent_Points = assign_points(Agent_Positions,coms,sides,Partition_Number,r_o,algorithm_type,E);
                Mass = mass_calc(Agent_Points, Density, Number_of_Agents, Partition_Number,algorithm_type);
                covered_mass = covered_mass_fun(Agent_Positions,Density,r_o,Partition_Number,Number_of_Agents,algorithm_type);
                
                if covered_mass >= Total_Mass
                    Percent_Covered = horzcat(Percent_Covered, 100);
                else
                    Percent_Covered = horzcat(Percent_Covered, 100*covered_mass/Total_Mass);
                end
                independent = 1:(count-1); % for plotting the coverage figure
                yyaxis(app.UIAxes2, 'left')
                plot(app.UIAxes2,independent, Percent_Covered, 'b');
                hold(app.UIAxes2, 'on')
                Centroids = centroid_finder(Agent_Points,Mass,Density,...
                    Number_of_Agents,Agent_Positions,Partition_Number);
                
                
                max_energy_of_agents = 100;
                wait_time = 3;
                
                if (count == 2)
                    E(:) = max_energy_of_agents;
                    charged_counter(:) = wait_time .* ones(1,Number_of_Agents);
                end
                
                % Move the agents
                [Agent_Positions,distance_travelled,E,charged_counter] = move_agents(Agent_Positions,Centroids,distance_travelled,Velocity_Type,...
                    velocity,max_velocity,MOVEMENTSCALE,algorithm_type,E,Mass,charged_counter);
                
                
                for k = 1 : Number_of_Agents % for saving to Excel
                    Latest_History(1:1,(2*k-1):2*k) = Agent_Positions(k,1:2);
                end
                
                History = vertcat(History,Latest_History); % for saving to Excel
                for i = 1:Number_of_Agents % Plot Agents and centroids
                    
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,Agent_Positions(i,1),...
                        Agent_Positions(i,2),90,'blue','filled');
                    app.UIAxes.XLim = [0 sides];
                    app.UIAxes.YLim = [0 sides];
                    label_agent_index = num2str(i);
                    t.FontSize = 20;
                    text(app.UIAxes, Agent_Positions(i,1) + sides/65, Agent_Positions(i,2) + sides/65,...
                        label_agent_index);
                    hold(app.UIAxes, 'on')
                    scatter(app.UIAxes,Centroids(i,1),Centroids(i,2),50,'red','filled');
                    hold(app.UIAxes, 'on')
                    
                    theta = linspace(0,2*pi,100);
                    x = r_o.*cos(theta) + Agent_Positions(i,1);
                    y = r_o.*sin(theta) + Agent_Positions(i,2);
                    
                    plot(app.UIAxes,x,y,'magenta')
                    
                    circle_Maker(app, Agent_Positions(i,1), Agent_Positions(i,2),r_o);
                end
                
                app.UITable.Data(:,1:2) = Agent_Positions;
                
                hold(app.UIAxes, 'on')
                voronoi(app.UIAxes,Agent_Positions(:,1),Agent_Positions(:,2));
                hold(app.UIAxes, 'on')
                [~, h] = contour(app.UIAxes,xx,yy,Density,4); % h is used for line width
                set(h,'linewidth',2);
                str = app.NameofCostFunction2EditField.Value;
                stra = 'Coverage and ';
                str = [stra str];
                independent = 1:count; % for plotting cost function 2 (distance travelled)
                yyaxis(app.UIAxes2, 'right')
                ylabel(app.UIAxes2, app.CostFunction2YAxisEditField.Value);
                title(app.UIAxes2, str)
                
                plot(app.UIAxes2,independent, distance_travelled, 'r');
                %  Plot of cost function on same axis as coverage plot  
                
                hold(app.UIAxes2, 'off')
                
                hold(app.UIAxes3, 'on')
                Plot_Assigned_Points(app, Agent_Points,Number_of_Agents,colours_energy,...
                    Agent_Positions,sides);
                hold(app.UIAxes3, 'on')
                pause(0.1);
                
                if (count-1 <= final_iteration) || (final_iteration <= 0)
                    cla(app.UIAxes)
                    clf(app.UIAxes);
                    
                    % Added check to see if movement of agents is under a threshold
                    %                     & all(abs(Old_Agent_Positions - Agent_Positions) < 0.3)
                    if (Density_variation_type == 2)
                        Density = iteration_variation_fun(Density,j,delay,Partition_Number,Agent_Positions,r_o);
                        Total_Mass = sum(Density, 'all');
                        Density_cumulative = vertcat(Density_cumulative, Density);
                    end
                end


                %% Plotting the total Mass over each iteration (time)
                
                % Add new values to the density graph
                iteration(count-1) = count - 1;
                Mass_Over_Time(count-1) = Total_Mass;
                
                
                for i = 1:Number_of_Agents
                    plot(iteration,'-*'); % Assume agents have 100% of energy at the start
                    if count == 2
                        hold on;
                    end
                end
                
                % Plot the number of Unpollinated Plants
                plot(iteration,Mass_Over_Time,'-o');
                title('Unpollinated Plants');
                xlabel('Time (min)');
                ylabel('Number of Unpollinated Plants');
                
                
                % If you want to make the graph look nicer (i.e. auto ajust the y axis, comment these two lines out
                %                 ylim manual
                %                 ylim([0 round(Initial_Mass,1,'significant')]);
                
                if count == 2
                    hold on;
                end
                
                % End plot of density
                
                j = j + 1;
                app.CurrentIterationminEditField.Value = count;
                
            end
            
            hold off;
            
            fprintf('The initial number (mass) of flowers that needed to be pollinated in the area are %d.\n',Initial_Mass);
            fprintf('The final number (mass) of flowers that still need to be pollinated in the area are %d.\n',round(Total_Mass));
            fprintf('Hence, the total number of flowers pollinated are %d at an average rate of %d flowers per minute over %d minutes.\n',...
                Initial_Mass - round(Total_Mass),round((Initial_Mass - round(Total_Mass))/final_iteration),final_iteration);
            
            
            %             fprintf('Saving the agent data.\n');
            
            %             History2 = History*(arena_dimensions/sides);
            %             delete agentData.xlsx
            %             delete agentData.mat
            %             xlswrite('agentData.xlsx',History,1);
            %             xlswrite('agentData.xlsx',History2,2);
            %             xlswrite('agentData.xlsx',Percent_Covered,3);
            %             xlswrite('agentData.xlsx',distance_travelled,4);
            %             xlswrite('agentData.xlsx',Density_cumulative, 5);
            %             Extra_info_matrix = zeros(1,3);
            %             Extra_info_matrix(1,1) = r_o;
            %             Extra_info_matrix(1,2) = r_c;
            %             Extra_info_matrix(1,3) = Density_variation_type;
            %             xlswrite('agentData.xlsx', Extra_info_matrix, 6);
            %             H = History;
            %             save('agentData.mat','H');
            %             clear Excel;
            %             system('taskkill /F /IM EXCEL.EXE'); % Kill leftover Excel process
            
            fprintf('The simulation ran successfully!\n');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %this resets everything if the reset button is pushed
            
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            app.CurrentIterationminEditField.Value = 1;
            n = app.NumberofAgentsEditField.Value;
            L = app.SideLengthmEditField.Value;
            
            Agent_Positions = zeros(n,3);
            
            for i = 1:n
                Agent_Positions(i,1) = L*((.51-.49)*rand+.49);
                Agent_Positions(i,2) = L*((.51-.49)*rand+.49);
                Agent_Positions(i,3) = i;
            end
            
            app.UITable.Data = Agent_Positions;
            
        end

        % Value changed function: NumberofAgentsEditField
        function NumberofAgentsEditFieldValueChanged(app, event)
            %This section re-randomizes the data if the number of agents is changed
            
            n = app.NumberofAgentsEditField.Value;
            L = app.SideLengthmEditField.Value;
            
            Agent_Positions = zeros(n,3);
            
            for i = 1:n
                Agent_Positions(i,1) = L*((.51-.49)*rand+.49);
                Agent_Positions(i,2) = L*((.51-.49)*rand+.49);
                Agent_Positions(i,3) = i;
            end
            
            app.UITable.Data = Agent_Positions;
            
        end

        % Value changed function: SideLengthmEditField
        function SideLengthmEditFieldValueChanged(app, event)
            %This section re-randomizes the data if the side length is changed
            
            n = app.NumberofAgentsEditField.Value;
            L = app.SideLengthmEditField.Value;
            
            Agent_Positions = zeros(n,2);
            
            for i = 1:n
                Agent_Positions(i,1) = L*((.51-.49)*rand+.49);
                Agent_Positions(i,2) = L*((.51-.49)*rand+.49);
            end
            
            app.UITable.Data(:,1:2) = Agent_Positions;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Arena')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Coverage')
            xlabel(app.UIAxes2, 'Iteration Number')
            ylabel(app.UIAxes2, 'Percent Covered')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Assigned Points')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Communication Graph')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes4.YTick = [0 0.5 1];
            app.NumberofAgentsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofAgentsEditFieldLabel.Text = 'Number of Agents';
            app.NumberofAgentsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofAgentsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofAgentsEditFieldValueChanged, true);
            app.NumberofAgentsEditField.Value = 3;
            app.SideLengthmEditFieldLabel = uilabel(app.UIFigure);
            app.SideLengthmEditFieldLabel.Text = 'Side Length (m)';
            app.SideLengthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SideLengthmEditField.ValueChangedFcn = createCallbackFcn(app, @SideLengthmEditFieldValueChanged, true);
            app.SideLengthmEditField.Value = 50;
            app.PartitionNumberEditFieldLabel = uilabel(app.UIFigure);
            app.PartitionNumberEditFieldLabel.Text = 'Partition Number';
            app.PartitionNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.PartitionNumberEditField.Value = 2;
            app.RadiusofObservationEditFieldLabel = uilabel(app.UIFigure);
            app.RadiusofObservationEditFieldLabel.Text = 'Radius of Observation';
            app.RadiusofObservationEditField = uieditfield(app.UIFigure, 'numeric');
            app.RadiusofObservationEditField.Value = 5;
            app.RadiusofCommunicationEditFieldLabel = uilabel(app.UIFigure);
            app.RadiusofCommunicationEditFieldLabel.Text = 'Radius of Communication';
            app.RadiusofCommunicationEditField = uieditfield(app.UIFigure, 'numeric');
            app.RadiusofCommunicationEditField.Value = 10;
            app.VelocityDropDownLabel = uilabel(app.UIFigure);
            app.VelocityDropDownLabel.Text = 'Velocity';
            app.VelocityDropDown = uidropdown(app.UIFigure);
            app.VelocityDropDown.Items = {'Constant Velocity', 'Proportional Velocity'};
            app.VelocityDropDown.Value = 'Proportional Velocity';
            app.MaximumVelocitymminEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumVelocitymminEditFieldLabel.Text = 'Maximum Velocity m/min';
            app.MaximumVelocitymminEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumVelocitymminEditField.Value = 500;
            app.DensityTypeDropDownLabel = uilabel(app.UIFigure);
            app.DensityTypeDropDownLabel.Text = 'Density Type';
            app.DensityTypeDropDown = uidropdown(app.UIFigure);
            app.DensityTypeDropDown.Items = {'Function', 'Matrix'};
            app.DensityTypeDropDown.Value = 'Matrix';
            app.DensityTimeVarianceDropDownLabel = uilabel(app.UIFigure);
            app.DensityTimeVarianceDropDownLabel.Text = 'Density Time-Variance';
            app.DensityTimeVarianceDropDown = uidropdown(app.UIFigure);
            app.DensityTimeVarianceDropDown.Items = {'Static', 'Dynamic'};
            app.DensityTimeVarianceDropDown.Value = 'Dynamic';
            app.DensityFunctionEditFieldLabel = uilabel(app.UIFigure);
            app.DensityFunctionEditFieldLabel.Text = 'Density Function';
            app.DensityFunctionEditField = uieditfield(app.UIFigure, 'text');
            app.DelayEditFieldLabel = uilabel(app.UIFigure);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.UIFigure, 'numeric');
            app.DelayEditField.Value = 1;
            app.FinalIterationminEditFieldLabel = uilabel(app.UIFigure);
            app.FinalIterationminEditFieldLabel.Text = 'Final Iteration (min)';
            app.FinalIterationminEditField = uieditfield(app.UIFigure, 'numeric');
            app.FinalIterationminEditField.Value = 10;
            app.AlgorithmDropDownLabel = uilabel(app.UIFigure);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.UIFigure);
            app.AlgorithmDropDown.Items = {'Regular', 'Custom'};
            app.AlgorithmDropDown.Value = 'Regular';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X'; 'Y'; '(Agent #)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true false];
            app.RandomizePositionsButton = uibutton(app.UIFigure, 'push');
            app.RandomizePositionsButton.ButtonPushedFcn = createCallbackFcn(app, @RandomizePositionsButtonPushed, true);
            app.RandomizePositionsButton.Text = 'Randomize Positions';
            app.NameofCostFunction2EditFieldLabel = uilabel(app.UIFigure);
            app.NameofCostFunction2EditFieldLabel.Text = 'Name of Cost Function 2';
            app.NameofCostFunction2EditField = uieditfield(app.UIFigure, 'text');
            app.NameofCostFunction2EditField.Value = 'Energy';
            app.CostFunction2YAxisEditFieldLabel = uilabel(app.UIFigure);
            app.CostFunction2YAxisEditFieldLabel.Text = 'Cost Function 2 Y-Axis';
            app.CostFunction2YAxisEditField = uieditfield(app.UIFigure, 'text');
            app.CostFunction2YAxisEditField.Value = 'Distance Travelled';
            app.CurrentIterationminEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentIterationminEditFieldLabel.Text = 'Current Iteration (min)';
            app.CurrentIterationminEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentIterationminEditField.Value = 1;
            app.VelocityMovementScaleEditFieldLabel = uilabel(app.UIFigure);
            app.VelocityMovementScaleEditFieldLabel.Text = 'Velocity/MovementScale';
            app.VelocityMovementScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocityMovementScaleEditField.Value = 1;
            app.DensityMatrixNameEditFieldLabel = uilabel(app.UIFigure);
            app.DensityMatrixNameEditFieldLabel.Text = 'Density Matrix Name';
            app.DensityMatrixNameEditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Lloyd_App_final

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end